classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        TypzwarciaDropDown       matlab.ui.control.DropDown
        TypzwarciaDropDownLabel  matlab.ui.control.Label
        UkadDropDown             matlab.ui.control.DropDown
        UkadDropDownLabel        matlab.ui.control.Label
        GenerujButton            matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        ImportButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
              % Tutaj umieść kod obsługi przycisku "Import"
        % Możesz skorzystać z przykładu z wcześniejszej odpowiedzi, aby otworzyć plik CSV i przetworzyć dane.
        % Na przykład:
        [filename, pathname] = uigetfile({'*.csv', 'Pliki CSV (*.csv)'; '*.txt', 'Pliki tekstowe (*.txt)'}, 'Wybierz plik CSV');
        if ischar(filename) && ischar(pathname)
            % Wybrane plik zostanie otwarty i przetworzony
            fullFileName = fullfile(pathname, filename);
            % Tutaj możesz dodać kod do obsługi wybranego pliku CSV
            % Wybrane plik zostanie otwarty i przetworzony
     
        % Wczytaj dane z pliku CSV (przykładowo używając funkcji csvread lub readtable)
        % Przykładowy kod załadowania danych (w zależności od formatu pliku CSV):
 data = readtable(fullFileName); % Jeśli używasz tabeli
%         data = csvread(fullFileName); % Jeśli używasz macierzy
        display(data);
        % Zaktualizuj dane w UITable
        app.UITable.Data = data; % Aktualizuj dane w UITable
        app.UITable.ColumnName = data.Properties.VariableNames; % Przypisz nazwy kolumn
        app.UITable.RowName = {}; % Wyczyść nazwy wierszy
        end
        end

        % Button pushed function: GenerujButton
        function GenerujButtonPushed(app, event)
            % Odczytaj wybrany układ z listy rozwijalnej
    wybranyUklad = app.UkadDropDown.Value;
    wybranyZwarcie = app.TypzwarciaDropDown.Value;

    % Odczytaj dane z UITable
    dane = app.UITable.Data;
      % Przypisz dane z każdego wiersza do odpowiednich zmiennych
    for i = 1:size(dane, 1)
        daneWiersza{i} = dane(i, :);
    end

    % Teraz dane z każdego wiersza są przechowywane w komórkach 'daneWiersza'
    % Możesz dalej przetwarzać te dane w zależności od potrzeb.

    % Tutaj możesz użyć 'daneWiersza' do generowania wykresu lub do innych operacji.

    % Wyświetlenie komunikatu po naciśnięciu przycisku
    

    % Tutaj możesz utworzyć tytuł wykresu na podstawie wybranego układu
    tytulWykresu = ['Wykres dla układu ' wybranyUklad ' zwarcie ' wybranyZwarcie];
    
    % Ustaw tytuł wykresu
    title(app.UIAxes, tytulWykresu);

    % Tutaj możesz dalej generować i rysować wykres w zależności od wybranego układu
    % oraz aktualnych danych w UITable.
    % Możesz również dostosować inne właściwości wykresu w UIAxes, jak xlabel, ylabel itp.
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
              komunikat = sprintf(['Witaj w kreatorze wykresów wektorowych do ćwiczenia 1! Aby zaimportować dane utwórz plik .csv. ' ...
                  'Ważne:Aby program działał prawidłowo nazwy kolumn jak i dane powinny pasować. Polecam zobaczyć plik example.csv.' ...
                  'Jeżeli dane się załadowały prawidłowo, powinne się po kilku sekundach dane pojawić w tabeli oraz powinny się zmienić wartości..' ...
                  'Sepratorem liczb jest kropka a przecinek do wartości CSV.  ']);
    uialert(app.UIFigure, komunikat, 'Jak używać', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'I_PL1'; 'I_PL2'; 'I_PL3'; 'I_N'; 'I_SL1'; 'I_SL2'; 'I_SL3'; 'I_SL0'; 'I_SL1-L2'; 'I_SL2-L3'; 'I_SL1-L3'};
            app.UITable.RowName = {};
            app.GenerujButton = uibutton(app.UIFigure, 'push');
            app.GenerujButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujButtonPushed, true);
            app.GenerujButton.Text = 'Generuj';
            app.UkadDropDownLabel = uilabel(app.UIFigure);
            app.UkadDropDownLabel.Text = 'Układ';
            app.UkadDropDown = uidropdown(app.UIFigure);
            app.UkadDropDown.Items = {'Gwiazda pełna', 'Gwiazda niepełna', 'Trójkąt', 'Holmgreen', 'Krzyżowy'};
            app.UkadDropDown.Value = 'Trójkąt';
            app.TypzwarciaDropDownLabel = uilabel(app.UIFigure);
            app.TypzwarciaDropDownLabel.Text = 'Typ zwarcia';
            app.TypzwarciaDropDown = uidropdown(app.UIFigure);
            app.TypzwarciaDropDown.Items = {' L1-N (1)', 'L2-N (2)', 'L3-N (3)', 'L1-L2-N (4)', 'L1-L3-N (5)', 'L2-L3-N (6)', 'L1-L2 (7)', 'L1-L3 (8)', 'L2-L3 (9)', 'L1-L2-L3 (10)'};
            app.TypzwarciaDropDown.Value = ' L1-N (1)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end